classdef DisctreteTimeSignalCompression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DiscreteTimeSignalCompressionLabel  matlab.ui.control.Label
        COMPRESSButton       matlab.ui.control.Button
        AddDiscreteSignalValueEditFieldLabel  matlab.ui.control.Label
        AddDiscreteSignalValueEditField  matlab.ui.control.NumericEditField
        EntertheCompressionFactorEditFieldLabel  matlab.ui.control.Label
        EntertheCompressionFactorEditField  matlab.ui.control.NumericEditField
        UITable              matlab.ui.control.Table
        ADDButton            matlab.ui.control.Button
        ReadExcelDataButton  matlab.ui.control.Button
        UITable_2            matlab.ui.control.Table
        REMOVEButton         matlab.ui.control.Button
        RemoventhDiscreteValueEditFieldLabel  matlab.ui.control.Label
        RemoventhDiscreteValueEditField  matlab.ui.control.NumericEditField
        ClearDataButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        t % input table data
        t2 % output table data
    end
    
    methods (Access = private)
        
        function y = sigcompress(app, x, m) %x=data | m=factor
            y = []; %create a new array for the "new" signal
            a = [1:width(x)]; %create the "x-axis" array with 0 as origin
            for i = 1:width(x)
                for j = a(i)
                    if mod(j, m)==0
                        y = [y x(1, i)]; %if its divisible, append that data
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: COMPRESSButton
        function COMPRESSButtonPushed(app, event)
            x = [app.UITable.Data];
            m = app.EntertheCompressionFactorEditField.Value;
            y = sigcompress(app, x, m);
            
            app.UITable_2.Data = y;
            
            stem(app.UIAxes_2, y{:,:});
            
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            x = app.AddDiscreteSignalValueEditField.Value;
            if (isempty(app.t) == 1)
                app.t = table;
            else
                app.t = app.t;
            end
            nd = {x};
            app.UITable.Data = [app.t nd];
            app.t = app.UITable.Data;
            
            stem(app.UIAxes, app.t{:, :});
        end

        % Button pushed function: ReadExcelDataButton
        function ReadExcelDataButtonPushed(app, event)
            app.t = readtable("discrete signal data.xlsx", "Sheet", 1);
            app.UITable.Data = app.t;
            
            stem(app.UIAxes, app.t{:, :});
        end

        % Button pushed function: REMOVEButton
        function REMOVEButtonPushed(app, event)
            rd = app.RemoventhDiscreteValueEditField.Value;
            app.t (:, rd) = [];
            app.UITable.Data = app.t;
            stem(app.UIAxes, app.t{:, :});
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.t(:, :) = [];
            app.UITable.Data = app.t;
            stem(app.UIAxes, app.t{:, :});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiscreteTimeSignalCompressionLabel = uilabel(app.UIFigure);
            app.DiscreteTimeSignalCompressionLabel.Text = 'Discrete Time Signal Compression';
            app.COMPRESSButton = uibutton(app.UIFigure, 'push');
            app.COMPRESSButton.ButtonPushedFcn = createCallbackFcn(app, @COMPRESSButtonPushed, true);
            app.COMPRESSButton.Text = 'COMPRESS';
            app.AddDiscreteSignalValueEditFieldLabel = uilabel(app.UIFigure);
            app.AddDiscreteSignalValueEditFieldLabel.Text = 'Add Discrete Signal Value';
            app.AddDiscreteSignalValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntertheCompressionFactorEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheCompressionFactorEditFieldLabel.Text = 'Enter the Compression Factor';
            app.EntertheCompressionFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.ReadExcelDataButton = uibutton(app.UIFigure, 'push');
            app.ReadExcelDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadExcelDataButtonPushed, true);
            app.ReadExcelDataButton.Text = 'Read Excel Data';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.REMOVEButton = uibutton(app.UIFigure, 'push');
            app.REMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEButtonPushed, true);
            app.REMOVEButton.Text = 'REMOVE';
            app.RemoventhDiscreteValueEditFieldLabel = uilabel(app.UIFigure);
            app.RemoventhDiscreteValueEditFieldLabel.Text = 'Remove nth Discrete Value';
            app.RemoventhDiscreteValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisctreteTimeSignalCompression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end